classdef PatGPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Switch                          matlab.ui.control.Switch
        AssignmentNumber                matlab.ui.control.NumericEditField
        AssignmentNumberEditFieldLabel  matlab.ui.control.Label
        Name                            matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        ProblemNumber                   matlab.ui.control.NumericEditField
        ProblemNumberEditFieldLabel     matlab.ui.control.Label
        PublishButton                   matlab.ui.control.Button
        OutputTextArea                  matlab.ui.control.TextArea
        OutputTextAreaLabel             matlab.ui.control.Label
        InputTextArea                   matlab.ui.control.TextArea
        InputTextAreaLabel              matlab.ui.control.Label
        AskButton                       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AskButton
        function AskButtonPushed(app, event)
            

            % Import Raw Data
            raw = strcat(string(app.InputTextArea.Value'))
            
            % Set up Python Fuction Data
            prompt = ""
            name = string(app.Name.Value)
            probNum = app.ProblemNumber.Value
            assign = app.AssignmentNumber.Value
            toggle = app.Switch.Value

            %Handle New Lines
            for(i = 1:numel(raw))
                prompt = strcat(prompt, raw(i))
            end

            % Implement Python Code
            response = pyrunfile("/Users/ethanvosburg/Documents/git/ENGR-220-MatLab/FinalProject/matLabGPT.py",...
                'reply', promptIn = prompt, nameIn = name, probNumIn = probNum, assignIn = assign, toggleIn = toggle)

            % Output Code
            app.OutputTextArea.Value = string(response);
        end

        % Button pushed function: PublishButton
        function PublishButtonPushed(app, event)
            % Open Generated Code
            genCode = fopen("/Users/ethanvosburg/Documents/git/ENGR-220-MatLab/FinalProject/GeneratedCode.m", "w+")
            fprintf( genCode, "%s\n", string(app.OutputTextArea.Value) )
            fclose(genCode)

            % Publish the File
            publish("GeneratedCode.m", 'pdf');

            % Open and View the Published File
            open('/Users/ethanvosburg/Documents/git/ENGR-220-MatLab/FinalProject/html/GeneratedCode.pdf')
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if (value == "PatGPT")
                % Make Name Visiable
                app.Name.Visible = true;
                app.NameEditFieldLabel.Visible = true;

                % Make Problem Number Visable
                app.ProblemNumber.Visible = true;
                app.ProblemNumberEditFieldLabel.Visible = true;
                
                % Make Assigmnet Number Visable
                app.AssignmentNumber.Visible = true;
                app.AssignmentNumberEditFieldLabel.Visible = true;
                
                % Make Publish Button Visable
                app.PublishButton.Visible = true;
            end
            if (value == "ChatGPT")
                % Make Name Hidden
                app.Name.Visible = false;
                app.NameEditFieldLabel.Visible = false;

                % Make Problem Number Hidden
                app.ProblemNumber.Visible = false;
                app.ProblemNumberEditFieldLabel.Visible = false;

                % Make Assigmnet Number Hidden
                app.AssignmentNumber.Visible = false;
                app.AssignmentNumberEditFieldLabel.Visible = false;

                % Make Publish Button Hidden
                app.PublishButton.Visible = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AskButton = uibutton(app.UIFigure, 'push');
            app.AskButton.ButtonPushedFcn = createCallbackFcn(app, @AskButtonPushed, true);
            app.AskButton.Text = 'Ask';
            app.InputTextAreaLabel = uilabel(app.UIFigure);
            app.InputTextAreaLabel.Text = 'Input';
            app.InputTextArea = uitextarea(app.UIFigure);
            app.InputTextArea.Value = {'In this problem you will replace the “loop method” of creating the x & y vectors used to plot the circle with an even better (faster) program.  Again, copy and paste your code into a new section for Problem 3, and then replace the loop with code to create the vectors using the linspace(_) function. You will see this is much easier, more efficient, and faster.  Better programming practice.'};
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.PublishButton = uibutton(app.UIFigure, 'push');
            app.PublishButton.ButtonPushedFcn = createCallbackFcn(app, @PublishButtonPushed, true);
            app.PublishButton.Visible = 'off';
            app.PublishButton.Text = 'Publish';
            app.ProblemNumberEditFieldLabel = uilabel(app.UIFigure);
            app.ProblemNumberEditFieldLabel.Visible = 'off';
            app.ProblemNumberEditFieldLabel.Text = 'Problem Number';
            app.ProblemNumber = uieditfield(app.UIFigure, 'numeric');
            app.ProblemNumber.RoundFractionalValues = 'on';
            app.ProblemNumber.ValueDisplayFormat = '%.f';
            app.ProblemNumber.Visible = 'off';
            app.ProblemNumber.Value = 4;
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Visible = 'off';
            app.NameEditFieldLabel.Text = 'Name';
            app.Name = uieditfield(app.UIFigure, 'text');
            app.Name.Visible = 'off';
            app.Name.Value = 'Ethan Vosburg';
            app.AssignmentNumberEditFieldLabel = uilabel(app.UIFigure);
            app.AssignmentNumberEditFieldLabel.Visible = 'off';
            app.AssignmentNumberEditFieldLabel.Text = 'Assignment Number';
            app.AssignmentNumber = uieditfield(app.UIFigure, 'numeric');
            app.AssignmentNumber.ValueDisplayFormat = '%.f';
            app.AssignmentNumber.Visible = 'off';
            app.AssignmentNumber.Value = 9;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'ChatGPT', 'PatGPT'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'ChatGPT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PatGPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end